classdef FarkleCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Player1Pic               matlab.ui.control.Image
        Player2Pic               matlab.ui.control.Image
        Banked2                  matlab.ui.container.Panel
        BankedSlot1_2            matlab.ui.control.Image
        BankedSlot2_2            matlab.ui.control.Image
        BankedSlot3_2            matlab.ui.control.Image
        BankedSlot4_2            matlab.ui.control.Image
        BankedSlot5_2            matlab.ui.control.Image
        BankedSlot6_2            matlab.ui.control.Image
        Banked1                  matlab.ui.container.Panel
        BankedSlot1              matlab.ui.control.Image
        BankedSlot2              matlab.ui.control.Image
        BankedSlot3              matlab.ui.control.Image
        BankedSlot4              matlab.ui.control.Image
        BankedSlot5              matlab.ui.control.Image
        BankedSlot6              matlab.ui.control.Image
        Rolled                   matlab.ui.container.Panel
        RolledSlot1              matlab.ui.control.Image
        RolledSlot2              matlab.ui.control.Image
        RolledSlot3              matlab.ui.control.Image
        RolledSlot4              matlab.ui.control.Image
        RolledSlot5              matlab.ui.control.Image
        RolledSlot6              matlab.ui.control.Image
        BankScoreButton          matlab.ui.control.Button
        RollDiceButton           matlab.ui.control.Button
        PlayerSelectButtonGroup  matlab.ui.container.ButtonGroup
        Player1Button            matlab.ui.control.RadioButton
        Player2Button            matlab.ui.control.RadioButton
        Player1Score             matlab.ui.control.NumericEditField
        Player2Score             matlab.ui.control.NumericEditField
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    properties (Access = public)
        DiceArray1
        rolledDice = randi(6, 1, 6)
        k = 0
        score
        farkleCheck
        myPlayerNum
        player
        otherPlayerNum
        channelID
        writeKey
        readKey
        readDelay
        writeDelay
        myScore
        otherPlayerScore
    end

    properties (Access = private)
        Property
    end 

    methods (Access = public)
        
        function render(app)
            app.RolledSlot(slot).ImageSource = 'dice_' + string(banked) + '.png';      
        end
        
    end

    methods (Access = private)
    function [] = WaitForOtherPlayer(app)
      playerWhoSentMsg = 0;   % Initializes variable to 0.

%This opens the images in a spinning GIF. This was our added animation.
app.RolledSlot1.ImageSource = "diceRollGif.gif"; 
app.RolledSlot2.ImageSource = "diceRollGif.gif";
app.RolledSlot3.ImageSource = "diceRollGif.gif";
app.RolledSlot4.ImageSource = "diceRollGif.gif";
app.RolledSlot5.ImageSource = "diceRollGif.gif";
app.RolledSlot6.ImageSource = "diceRollGif.gif";

    while playerWhoSentMsg ~= app.otherPlayerNum
        % Read a data table from the channel
          dataTable = thingSpeakRead(app.channelID, 'Fields', [1, 2, 3, 4], 'ReadKey', app.readKey, 'OutputFormat', 'Table') ; 

        % Access the entries in the table with useful info (entry 1
        % is timestamps)

         playerNum = dataTable.(2);
         player1Score = dataTable.(3);
         player2Score = dataTable.(4);
         ReadDice = dataTable.(5);


    try
    % Convert ReadDice{1} to a number array if possible
    app.rolledDice = str2num(ReadDice{1}); 

    % Check if the conversion was successful and if it contains enough elements
    if isempty(app.rolledDice) || any(isnan(app.rolledDice)) || length(app.rolledDice) < 6
        error('Invalid dice data.');
    end

    % Update images safely
    app.RolledSlot1.ImageSource = "dice_" + string(app.rolledDice(1)) + ".png";
    app.RolledSlot2.ImageSource = "dice_" + string(app.rolledDice(2)) + ".png";
    app.RolledSlot3.ImageSource = "dice_" + string(app.rolledDice(3)) + ".png";
    app.RolledSlot4.ImageSource = "dice_" + string(app.rolledDice(4)) + ".png";
    app.RolledSlot5.ImageSource = "dice_" + string(app.rolledDice(5)) + ".png";
    app.RolledSlot6.ImageSource = "dice_" + string(app.rolledDice(6)) + ".png";
    playerWhoSentMsg = dataTable.(2) ;

   catch
    % Handle error gracefully and allow program to continue running
    warning('Error processing dice roll.');
    end
    
    % For help with debugging:
        disp("I am player " + num2str(app.myPlayerNum) + ...
         " and I just read this data from the ThingSpeak channel:");
         disp([playerNum, player1Score, player2Score, app.rolledDice ]);

     % The first entry in the table should always be the
     % number of the player who last wrote the data
                
     % Delay before trying to read the online channel again
          pause(app.readDelay);
      end

    % The second entry represents the newly updated score info
    app.Player1Score.Value = dataTable.(3);
    app.Player2Score.Value = dataTable.(4);
    app.RolledSlot1.Visible = 'off';
    app.RolledSlot2.Visible = 'off';
    app.RolledSlot3.Visible = 'off';
    app.RolledSlot4.Visible = 'off';
    app.RolledSlot5.Visible = 'off';
    app.RolledSlot6.Visible = 'off';
    set(app.Rolled, 'Enable', 'on');


    end % WaitForOtherPlayer function end
        function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3,4 ], 'Values', [0, 0, 0, 0], 'WriteKey', app.writeKey);
        end

%The function here tell us what values are being sent back and forth. The
%displays inidcate that its the player number and the score obtained. 
        function [] = SendDataToOtherPlayer(app)
            pause(app.writeDelay);

            if app.myPlayerNum == 1
                disp("I sent this data to the ThingSpeak channel: ");
                disp([app.myPlayerNum, app.Player1Score.Value]);
                dice = app.rolledDice ;
                thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4], 'Values', {app.myPlayerNum, app.Player1Score.Value ,app.Player2Score.Value, num2str(dice) }, 'WriteKey', app.writeKey);

            elseif app.myPlayerNum == 2
                disp("I sent this data to the ThingSpeak channel: ");
                disp([app.myPlayerNum, app.Player2Score.Value]);
                dice = app.rolledDice;
                thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4], 'Values', {app.myPlayerNum, app.Player1Score.Value , app.Player2Score.Value , num2str(dice) } , 'WriteKey', app.writeKey);
            end
        end


        function [] = SendDataToOtherPlayerButtton(app)
            pause(app.writeDelay);

            if app.myPlayerNum == 1
                disp("I sent this data to the ThingSpeak channel: ");
                disp([app.myPlayerNum, app.Player1Score.Value, app.rolledDice]);
                dice = app.rolledDice ;
                tempVAr = 0;
                thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4], 'Values', {tempVAr, app.Player1Score.Value ,app.Player2Score.Value, num2str(dice) }, 'WriteKey', app.writeKey);

            elseif app.myPlayerNum == 2
                disp("I sent this data to the ThingSpeak channel: ");
                disp([app.myPlayerNum, app.Player2Score.Value, app.rolledDice]);
                dice = app.rolledDice;
                tempVAr = 0;
                thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4], 'Values', {tempVAr, app.Player1Score.Value , app.Player2Score.Value , num2str(dice) } , 'WriteKey', app.writeKey);
            end
        end

    end % private methods end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.DiceArray1 = [];   % Initializes a 1x6 row array to input dice values into
        %Adds intial filler color images to the opening game, in the alloted slots. 
        app.RolledSlot1.ImageSource = 'whiteSquare.png';
        app.RolledSlot2.ImageSource = 'whiteSquare.png';
        app.RolledSlot3.ImageSource = 'whiteSquare.png';
        app.RolledSlot4.ImageSource = 'whiteSquare.png';
        app.RolledSlot5.ImageSource = 'whiteSquare.png';
        app.RolledSlot6.ImageSource = 'whiteSquare.png';

        %Adds intial filler color images to the opening game, in the alloted slots.
        app.BankedSlot1.ImageSource = 'RedSquare.png';
        app.BankedSlot2.ImageSource = 'RedSquare.png';
        app.BankedSlot3.ImageSource = 'RedSquare.png';
        app.BankedSlot4.ImageSource = 'RedSquare.png';
        app.BankedSlot5.ImageSource = 'RedSquare.png';
        app.BankedSlot6.ImageSource = 'RedSquare.png';
         

        %Adds intial filler color images to the opening game, in the alloted slots.
        app.BankedSlot1_2.ImageSource = 'RedSquare.png';
        app.BankedSlot2_2.ImageSource = 'RedSquare.png';
        app.BankedSlot3_2.ImageSource = 'RedSquare.png';
        app.BankedSlot4_2.ImageSource = 'RedSquare.png';
        app.BankedSlot5_2.ImageSource = 'RedSquare.png';
        app.BankedSlot6_2.ImageSource = 'RedSquare.png';
        app.Button_2.Visible = 'off';

        % app.channelID = 2873883;
        % app.writeKey = "QOU172TZ2ODFHUDU";
        % app.readKey = "ZNAH15L5S38D4YE5";
        app.channelID = 2877355;
        app.writeKey = "VNPLWJ3R9FQ1RPN2";
        app.readKey = "92ABMTYNRSATKN2A";


        app.readDelay = 2;
        app.writeDelay = 2;
        app.myPlayerNum = 1; % Initializes player 1 as default
        app.otherPlayerNum = 2; % Initializes other player as player 2 by default

        set(app.BankScoreButton, 'Enable', 'off');

        app.ClearThingSpeakChannel(); 
        if app.myPlayerNum == 2
            app.WaitForOtherPlayer
        end


        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
          % Disables player select after first dice roll
          app.PlayerSelectButtonGroup.Enable = "off"; 
        
     
        %Creates a rolled GIF animation when the Roll Dice Button is
        %activated. 
        app.RolledSlot1.ImageSource = "diceRollGif.gif";
        app.RolledSlot2.ImageSource = "diceRollGif.gif";
        app.RolledSlot3.ImageSource = "diceRollGif.gif";
        app.RolledSlot4.ImageSource = "diceRollGif.gif";
        app.RolledSlot5.ImageSource = "diceRollGif.gif";
        app.RolledSlot6.ImageSource = "diceRollGif.gif";

        set(app.BankScoreButton, 'Enable', 'on')

            app.rolledDice = randi(6, 1, 6)
            app.RolledSlot1.ImageSource = 'dice_' + string(app.rolledDice(1)) + '.png';
            app.RolledSlot2.ImageSource = 'dice_' + string(app.rolledDice(2)) + '.png';
            app.RolledSlot3.ImageSource = 'dice_' + string(app.rolledDice(3)) + '.png';
            app.RolledSlot4.ImageSource = 'dice_' + string(app.rolledDice(4)) + '.png';
            app.RolledSlot5.ImageSource = 'dice_' + string(app.rolledDice(5)) + '.png';
            app.RolledSlot6.ImageSource = 'dice_' + string(app.rolledDice(6)) + '.png';

        [y, Fs] = audioread('roll.mp3'); % Load audio
            sound(y,Fs); % Play the audio

    % FARKLE CODE
   
    % Initialize farkleCheck
    app.farkleCheck = 0;
    farkleDice = app.rolledDice;    % Assigne rolledDice to farkleDice

    if app.RolledSlot1.Visible == 'off'  % Remove 1st element
        farkleDice(1) = 0;
    end

    if app.RolledSlot2.Visible == 'off' % Remove second element
        farkleDice(2) = 0;
    end

    if app.RolledSlot3.Visible == 'off' % Remove third element
        farkleDice(3) = 0;
    end

    if app.RolledSlot4.Visible == 'off' % Remove fourth element
        farkleDice(4) = 0;
    end

    if app.RolledSlot5.Visible == 'off' % Remove fifth element
        farkleDice(5) = 0;
    end

    if app.RolledSlot6.Visible == 'off' % Remove sixth element
        farkleDice(6) = 0;
    end

    display(farkleDice)
    
    % Count occurrences of each dice value (1-6)

    farkleCounts = histcounts(farkleDice, 1:7);

    % Check for a straight (1,2,3,4,5,6) → 1500 pts
    if all(farkleCounts == 1)
        app.farkleCheck = app.farkleCheck + 1500;
        return; % No need to check further
    end
    
    % Check for Three Pairs → 1500 pts
    if (sum(farkleCounts == 2)) == 3
        app.farkleCheck = app.farkleCheck + 1500;
        return;
    end

    % Check for Two Triplets → 2500 pts
    if sum(farkleCounts == 3) == 2
        app.farkleCheck = app.farkleCheck + 2500;
        return;
    end
    
    % Check for Four of a Kind with a Pair → 1500 pts
    if any(farkleCounts == 4) && any(farkleCounts == 2)
        app.farkleCheck = app.farkleCheck + 1500;
        return;
    end

    % Check for Three, Four, Five, and Six of a Kind
    for i = 1:6
        if farkleCounts(i) == 3
            if i == 1
                app.farkleCheck = app.farkleCheck + 300; % Three 1s = 300 pts
            else
                app.farkleCheck = app.farkleCheck + i * 100; % Three Xs = X * 100 pts
            end
        elseif farkleCounts(i) == 4
            app.farkleCheck = app.farkleCheck + 1000; % Four of a Kind = 1000 pts
        elseif farkleCounts(i) == 5
            app.farkleCheck = app.farkleCheck + 2000; % Five of a Kind = 2000 pts
        elseif farkleCounts(i) == 6
            app.farkleCheck = app.farkleCheck + 3000; % Six of a Kind = 3000 pts
        end
    end
    
    % Scoring for single 1s and 5s
    if farkleCounts(1) == 1 || farkleCounts(1) == 2 
    app.farkleCheck = app.farkleCheck + farkleCounts(1) * 100; % Each 1 = 100 pts
    end
    if farkleCounts(5) == 1 || farkleCounts(5) == 2 
    app.farkleCheck = app.farkleCheck + farkleCounts(5) * 50;  % Each 5 = 50 pts
    end

if app.farkleCheck == 0
    app.rolledDice = randi(6, 1, 6) ; % make it so when visibility if of rolledDice(V) = 0 
    app.RolledSlot1.ImageSource = 'dice_' + string(app.rolledDice(1)) + '.png';
    app.RolledSlot2.ImageSource = 'dice_' + string(app.rolledDice(2)) + '.png';
    app.RolledSlot3.ImageSource = 'dice_' + string(app.rolledDice(3)) + '.png';
    app.RolledSlot4.ImageSource = 'dice_' + string(app.rolledDice(4)) + '.png';
    app.RolledSlot5.ImageSource = 'dice_' + string(app.rolledDice(5)) + '.png';
    app.RolledSlot6.ImageSource = 'dice_' + string(app.rolledDice(6)) + '.png';

    % Resets rolled dice images to be invisible
    app.RolledSlot1.Visible = 'on';
    app.RolledSlot2.Visible = 'on';
    app.RolledSlot3.Visible = 'on';
    app.RolledSlot4.Visible = 'on';
    app.RolledSlot5.Visible = 'on';
    app.RolledSlot6.Visible = 'on';

    % Resets banked dice images to be invisible
    app.BankedSlot1.Visible = 'off';
    app.BankedSlot2.Visible = 'off';
    app.BankedSlot3.Visible = 'off';
    app.BankedSlot4.Visible = 'off';
    app.BankedSlot5.Visible = 'off';
    app.BankedSlot6.Visible = 'off';

    app.BankedSlot1_2.Visible = 'off';
    app.BankedSlot2_2.Visible = 'off';
    app.BankedSlot3_2.Visible = 'off';
    app.BankedSlot4_2.Visible = 'off';
    app.BankedSlot5_2.Visible = 'off';
    app.BankedSlot6_2.Visible = 'off';

    if app.player == 1
        app.score = 0;  % Initialize app.score
        app.player = 2;
    elseif app.player == 2
        app.score = 0;  % Initialize app.score
        app.player = 1;
    end
end

app.SendDataToOtherPlayerButtton();


        end

        % Image clicked function: RolledSlot1
        function RolledSlot1ImageClicked(app, event)

            app.RolledSlot1.Visible = 'off';
               %If player one is the one thats playing, it allows them
               %to see the die that is rolled randomly in the selected rolled
               %slot.
            if app.myPlayerNum == 1
               app.BankedSlot1.Visible = 'on';
               app.BankedSlot1.ImageSource = app.RolledSlot1.ImageSource;
            end
             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot. 
            if app.myPlayerNum == 2 
                app.BankedSlot1_2.Visible = 'on';
                app.BankedSlot1_2.ImageSource = app.RolledSlot1.ImageSource;
            end

            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data.
            Die1String = string(app.RolledSlot1.ImageSource);
            Die1ValOnly = erase(Die1String, "dice_"); Die1ValOnly = erase(Die1ValOnly, ".png");
            Die1ValDouble = double(Die1ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die1ValDouble];

            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(1)]
        
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio
        end

        % Image clicked function: RolledSlot2
        function RolledSlot2ImageClicked(app, event)
            app.RolledSlot2.Visible = 'off';
            
             %If player one is the one thats playing, it allows them
               %to see the die that is rolled randomly in the selected rolled
               %slot.
            if app.myPlayerNum == 1 
            app.BankedSlot2.Visible = 'on';
            app.BankedSlot2.ImageSource = app.RolledSlot2.ImageSource;
            end

             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot.
            if app.myPlayerNum ==2 
                app.BankedSlot2_2.Visible = 'on';
                app.BankedSlot2_2.ImageSource = app.RolledSlot2.ImageSource;
            end


            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data.
            Die2String = string(app.RolledSlot2.ImageSource);
            Die2ValOnly = erase(Die2String, "dice_"); Die2ValOnly = erase(Die2ValOnly, ".png");
            Die2ValDouble = double(Die2ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die2ValDouble];

            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(2)]

            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot3
        function RolledSlot3ImageClicked(app, event)
            app.RolledSlot3.Visible = 'off';
            %If player one is the one thats playing, it allows them
            %to see the die that is rolled randomly in the selected rolled
            %slot.
            if app.myPlayerNum == 1 
              app.BankedSlot3.Visible = 'on';
              app.BankedSlot3.ImageSource = app.RolledSlot3.ImageSource;
            end

             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot.
            if app.myPlayerNum == 2 
                app.BankedSlot3_2.Visible = 'on';
                app.BankedSlot3_2.ImageSource = app.RolledSlot3.ImageSource;
            end
            
            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data.
            Die3String = string(app.RolledSlot3.ImageSource);
            Die3ValOnly = erase(Die3String, "dice_"); Die3ValOnly = erase(Die3ValOnly, ".png");
            Die3ValDouble = double(Die3ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die3ValDouble];

            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(3)]

            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot4
        function RolledSlot4ImageClicked(app, event)
            app.RolledSlot4.Visible = 'off';
            %If player one is the one thats playing, it allows them
            %to see the die that is rolled randomly in the selected rolled
            %slot.
            if app.myPlayerNum == 1
              app.BankedSlot4.Visible = 'on';
              app.BankedSlot4.ImageSource = app.RolledSlot4.ImageSource;
            end

             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot.
            if app.myPlayerNum == 2 
                app.BankedSlot4_2.Visible = 'on';
                app.BankedSlot4_2.ImageSource = app.RolledSlot4.ImageSource;
            end
           
            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data.
            Die4String = string(app.RolledSlot4.ImageSource);
            Die4ValOnly = erase(Die4String, "dice_"); Die4ValOnly = erase(Die4ValOnly, ".png");
            Die4ValDouble = double(Die4ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die4ValDouble];

            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(4)]

            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot5
        function RolledSlot5ImageClicked(app, event)
            app.RolledSlot5.Visible = 'off';
            %If player one is the one thats playing, it allows them
            %to see the die that is rolled randomly in the selected rolled
            %slot.
            if app.myPlayerNum == 1 
              app.BankedSlot5.Visible = 'on';
              app.BankedSlot5.ImageSource = app.RolledSlot5.ImageSource;
            end

             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot.
            if app.myPlayerNum == 2 
                app.BankedSlot5_2.Visible = 'on';
                app.BankedSlot5_2.ImageSource = app.RolledSlot5.ImageSource;
            end
            
            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data.
            Die5String = string(app.RolledSlot5.ImageSource);
            Die5ValOnly = erase(Die5String, "dice_"); Die5ValOnly = erase(Die5ValOnly, ".png");
            Die5ValDouble = double(Die5ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die5ValDouble];
       
            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(5)]
           
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot6
        function RolledSlot6ImageClicked(app, event)
            app.RolledSlot6.Visible = 'off';
            %If player one is the one thats playing, it allows them
            %to see the die that is rolled randomly in the selected rolled
            %slot.
            if app.myPlayerNum == 1
                app.BankedSlot6.Visible = 'on';
                app.BankedSlot6.ImageSource = app.RolledSlot6.ImageSource;
            end

             %If player two is the one thats playing, it allowes them
             %to see the die that is rolled randomly in the selected
             %slot.
            if app.myPlayerNum == 2 
                app.BankedSlot6_2.Visible = 'on';
                app.BankedSlot6_2.ImageSource = app.RolledSlot6.ImageSource;
            end
            
            %This code takes thes images and then extracts the numerical
            %values that are correlated with them. These values are then
            %places in arrays to be stored and read as data. 
            Die6String = string(app.RolledSlot6.ImageSource);
            Die6ValOnly = erase(Die6String, "dice_"); Die6ValOnly = erase(Die6ValOnly, ".png");
            Die6ValDouble = double(Die6ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die6ValDouble];

            % app.DiceArray1 = [app.DiceArray1; app.rolledDice(6)]
         
           [y, Fs] = audioread('woosh.mp3'); % Load audio
           sound(y,Fs); % Play the audio

        end

        % Button pushed function: BankScoreButton
        function BankScoreButtonPushed(app, event)
[y, Fs] = audioread('BankScoreSound.mp3'); % Load audio
           sound(y,Fs);   

    % Resets rolled dice images to be invisible
    app.RolledSlot1.Visible = 'on';
    app.RolledSlot2.Visible = 'on';
    app.RolledSlot3.Visible = 'on';
    app.RolledSlot4.Visible = 'on';
    app.RolledSlot5.Visible = 'on';
    app.RolledSlot6.Visible = 'on';

    % Resets banked dice images to be invisible
    app.BankedSlot1.Visible = 'off';
    app.BankedSlot2.Visible = 'off';
    app.BankedSlot3.Visible = 'off';
    app.BankedSlot4.Visible = 'off';
    app.BankedSlot5.Visible = 'off';
    app.BankedSlot6.Visible = 'off';

    app.BankedSlot1_2.Visible = 'off';
    app.BankedSlot2_2.Visible = 'off';
    app.BankedSlot3_2.Visible = 'off';
    app.BankedSlot4_2.Visible = 'off';
    app.BankedSlot5_2.Visible = 'off';
    app.BankedSlot6_2.Visible = 'off';

   % RollDiceButtonPushed(app, event);
   
    if app.myPlayerNum == 1
         % Initialize score
      app.score = 0;
      diceRolls = app.DiceArray1;
    

         % Get dice rolls from app
      app.DiceArray1 = [];
    
    % Count occurrences of each dice value (1-6)

      counts = histcounts(diceRolls, 1:7);

    % Check for a straight (1,2,3,4,5,6) → 1500 pts
    if all(counts == 1)
        app.score = app.score + 1500;
        app.Player1Score.Value = app.Player1Score.Value + app.score;
        return; % No need to check further
    end
    
    % Check for Three Pairs → 1500 pts
    if (sum(counts == 2)) == 3
        app.score = app.score + 1500;
        app.Player1Score.Value = app.Player1Score.Value + app.score;
        return;
    end

    % Check for Two Triplets → 2500 pts
    if sum(counts == 3) == 2
        app.score = app.score + 2500;
        app.Player1Score.Value = app.Player1Score.Value + app.score;
        return;
    end
    
    % Check for Four of a Kind with a Pair → 1500 pts
    if any(counts == 4) && any(counts == 2)
        app.score = app.score + 1500;
        app.Player1Score.Value = app.Player1Score.Value + app.score;
        return;
    end

    % Check for Three, Four, Five, and Six of a Kind
    for i = 1:6
        if counts(i) == 3
            if i == 1
                app.score = app.score + 300; % Three 1s = 300 pts
            else
                app.score = app.score + i * 100; % Three Xs = X * 100 pts
            end
        elseif counts(i) == 4
            app.score = app.score + 1000; % Four of a Kind = 1000 pts
        elseif counts(i) == 5
            app.score = app.score + 2000; % Five of a Kind = 2000 pts
        elseif counts(i) == 6
            app.score = app.score + 3000; % Six of a Kind = 3000 pts
        end
    end
    
    % Scoring for single 1s and 5s
    if counts(1) == 1 || counts(1) == 2 
     app.score = app.score + counts(1) * 100; % Each 1 = 100 pts
    end
    if counts(5) == 1 || counts(5) == 2 
     app.score = app.score + counts(5) * 50;  % Each 5 = 50 pts
    end
    
   %The code here indicates which of the players in the winner. The fisrt
   %player to reach 5000 points is the winner of the game. A winnng banner
   %will be displayed. 
     app.Player1Score.Value = app.Player1Score.Value + app.score;
    if app.Player1Score.Value > 5000
       app.Button_2.Visible = 'on';
    end

    app.player = 2;
    end

    if app.myPlayerNum == 2
         % Initialize app.score
    app.score = 0;
    diceRolls = app.DiceArray1;  
    app.DiceArray1 = [];
    % Count occurrences of each dice value (1-6)
    counts = histcounts(diceRolls, 1:7);
     
    % Check for a straight (1,2,3,4,5,6) → 1500 pts
    if all(counts == 1)
        app.score = app.score + 1500;
        app.Player2Score.Value = app.Player2Score.Value + app.score;
        return; % No need to check further
    end
    
    % Check for Three Pairs → 1500 pts
    if sum(counts == 2) == 3
        app.score = app.score + 1500;
        app.Player2Score.Value = app.Player2Score.Value + app.score;
        return;
    end

    % Check for Two Triplets → 2500 pts
    if sum(counts == 3) == 2
        app.score = app.score + 2500;
        app.Player2Score.Value = app.Player2Score.Value + app.score;
        return;
    end
    
    % Check for Four of a Kind with a Pair → 1500 pts
    if any(counts == 4) && any(counts == 2)
        app.score = app.score + 1500;
        app.Player2Score.Value = app.Player2Score.Value + app.score;
        return;
    end

    % Check for Three, Four, Five, and Six of a Kind
    for i = 1:6
        if counts(i) == 3
            if i == 1
                app.score = app.score + 300; % Three 1s = 300 pts
            else
                app.score = app.score + i * 100; % Three Xs = X * 100 pts
            end
        elseif counts(i) == 4
            app.score = app.score + 1000; % Four of a Kind = 1000 pts
        elseif counts(i) == 5
            app.score = app.score + 2000; % Five of a Kind = 2000 pts
        elseif counts(i) == 6
            app.score = app.score + 3000; % Six of a Kind = 3000 pts
        end
    end
    
    % Scoring for single 1s and 5s
    if counts(1) == 1 || counts(1) == 2 
    app.score = app.score + counts(1) * 100; % Each 1 = 100 pts
    end
    if counts(5) == 1 || counts(5) == 2 
    app.score = app.score + counts(5) * 50;  % Each 5 = 50 pts
    end

    %The code here indicates which of the players in the winner. The fisrt
   %player to reach 5000 points is the winner of the game. A winnng banner
   %will be displayed. 
    app.Player2Score.Value = app.Player2Score.Value + app.score;
    if app.Player2Score.Value > 5000
         app.Button_2.Visible = 'on';
    end
    
    app.player = 1;
    end 
   
    app.score
    set(app.Rolled, 'Enable', 'off');

    if app.myPlayerNum == 1
        set(app.RollDiceButton, 'Enable', 'off')
        set(app.BankScoreButton, 'Enable', 'off')
    end

    if app.myPlayerNum == 2
        set(app.RollDiceButton, 'Enable', 'off')
        set(app.BankScoreButton, 'Enable', 'off')
    end

     app.RolledSlot1.Visible = 'off';
     app.RolledSlot2.Visible = 'off';
     app.RolledSlot3.Visible = 'off';
     app.RolledSlot4.Visible = 'off';
     app.RolledSlot5.Visible = 'off';
     app.RolledSlot6.Visible = 'off';

     app.SendDataToOtherPlayer();

     app.WaitForOtherPlayer();

    set(app.RollDiceButton, 'Enable', 'on');

        end

        % Value changed function: Player1Score
        function Player1ScoreValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
       app.Button.Visible = 'off';
       [y, Fs] = audioread('OpeningSoundClip.mp3'); % Loads the audio file
            sound(y,Fs); % Plays the audio
        end

        % Button down function: PlayerSelectButtonGroup
        function PlayerSelectButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: PlayerSelectButtonGroup
        function PlayerSelectButtonGroupSelectionChanged(app, event)
      %This code distinguishes whih player is allowed to interact with the
      %roll and bank dice buttons. If it is player ones turn, they are not
      %able to, interact during players twos turn, and vise versa. 

            if app.PlayerSelectButtonGroup.SelectedObject == app.Player1Button
             app.myPlayerNum = 1;
             app.otherPlayerNum = 2;
             set(app.RollDiceButton, 'Enable', 'on');

         elseif app.PlayerSelectButtonGroup.SelectedObject == app.Player2Button
             app.myPlayerNum = 2;
             app.otherPlayerNum = 1;
             set(app.RollDiceButton, 'Enable', 'off');
             set(app.Rolled, 'Enable', 'off');
             app.WaitForOtherPlayer();
             set(app.RollDiceButton, 'Enable', 'on');
             set(app.Rolled, 'Enable', 'on');
         end
         display(app.myPlayerNum)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ScoreGuide.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'PlayerScores.png');
            app.Player2Score = uieditfield(app.UIFigure, 'numeric');
            app.Player2Score.Editable = 'off';
            app.Player1Score = uieditfield(app.UIFigure, 'numeric');
            app.Player1Score.ValueChangedFcn = createCallbackFcn(app, @Player1ScoreValueChanged, true);
            app.Player1Score.Editable = 'off';
            app.PlayerSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.PlayerSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlayerSelectButtonGroupSelectionChanged, true);
            app.PlayerSelectButtonGroup.Title = 'Player Select';
            app.PlayerSelectButtonGroup.ButtonDownFcn = createCallbackFcn(app, @PlayerSelectButtonGroupButtonDown, true);
            app.Player2Button = uiradiobutton(app.PlayerSelectButtonGroup);
            app.Player2Button.Text = 'Player 2';
            app.Player1Button = uiradiobutton(app.PlayerSelectButtonGroup);
            app.Player1Button.Text = 'Player 1';
            app.Player1Button.Value = true;
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.BankScoreButton = uibutton(app.UIFigure, 'push');
            app.BankScoreButton.ButtonPushedFcn = createCallbackFcn(app, @BankScoreButtonPushed, true);
            app.BankScoreButton.Text = 'Bank Score';
            app.Rolled = uipanel(app.UIFigure);
            app.RolledSlot6 = uiimage(app.Rolled);
            app.RolledSlot6.ImageClickedFcn = createCallbackFcn(app, @RolledSlot6ImageClicked, true);
            app.RolledSlot5 = uiimage(app.Rolled);
            app.RolledSlot5.ImageClickedFcn = createCallbackFcn(app, @RolledSlot5ImageClicked, true);
            app.RolledSlot4 = uiimage(app.Rolled);
            app.RolledSlot4.ImageClickedFcn = createCallbackFcn(app, @RolledSlot4ImageClicked, true);
            app.RolledSlot3 = uiimage(app.Rolled);
            app.RolledSlot3.ImageClickedFcn = createCallbackFcn(app, @RolledSlot3ImageClicked, true);
            app.RolledSlot2 = uiimage(app.Rolled);
            app.RolledSlot2.ImageClickedFcn = createCallbackFcn(app, @RolledSlot2ImageClicked, true);
            app.RolledSlot1 = uiimage(app.Rolled);
            app.RolledSlot1.ImageClickedFcn = createCallbackFcn(app, @RolledSlot1ImageClicked, true);
            app.Banked1 = uipanel(app.UIFigure);
            app.BankedSlot6 = uiimage(app.Banked1);
            app.BankedSlot5 = uiimage(app.Banked1);
            app.BankedSlot4 = uiimage(app.Banked1);
            app.BankedSlot3 = uiimage(app.Banked1);
            app.BankedSlot2 = uiimage(app.Banked1);
            app.BankedSlot1 = uiimage(app.Banked1);
            app.Banked2 = uipanel(app.UIFigure);
            app.BankedSlot6_2 = uiimage(app.Banked2);
            app.BankedSlot5_2 = uiimage(app.Banked2);
            app.BankedSlot4_2 = uiimage(app.Banked2);
            app.BankedSlot3_2 = uiimage(app.Banked2);
            app.BankedSlot2_2 = uiimage(app.Banked2);
            app.BankedSlot1_2 = uiimage(app.Banked2);
            app.Player2Pic = uiimage(app.UIFigure);
            app.Player2Pic.ImageSource = fullfile(pathToMLAPP, 'PlayerTwoIcon.png');
            app.Player1Pic = uiimage(app.UIFigure);
            app.Player1Pic.ImageSource = fullfile(pathToMLAPP, 'PlayerOneIcon.png');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'YouWinDisplay.png');
            app.Button_2.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'FarkleOpeningFinal.png');
            app.Button.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FarkleCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end