classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Player1Pic             matlab.ui.control.Image
        Player2Pic             matlab.ui.control.Image
        Banked2                matlab.ui.container.Panel
        BankedSlot1_2          matlab.ui.control.Image
        BankedSlot2_2          matlab.ui.control.Image
        BankedSlot3_2          matlab.ui.control.Image
        BankedSlot4_2          matlab.ui.control.Image
        BankedSlot5_2          matlab.ui.control.Image
        BankedSlot6_2          matlab.ui.control.Image
        ScoringGuide           matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        Scores                 matlab.ui.container.Panel
        Player2Score           matlab.ui.control.NumericEditField
        Player2EditFieldLabel  matlab.ui.control.Label
        Player1Score           matlab.ui.control.NumericEditField
        Player1EditFieldLabel  matlab.ui.control.Label
        Banked1                matlab.ui.container.Panel
        BankedSlot1            matlab.ui.control.Image
        BankedSlot2            matlab.ui.control.Image
        BankedSlot3            matlab.ui.control.Image
        BankedSlot4            matlab.ui.control.Image
        BankedSlot5            matlab.ui.control.Image
        BankedSlot6            matlab.ui.control.Image
        Rolled                 matlab.ui.container.Panel
        RolledSlot1            matlab.ui.control.Image
        RolledSlot2            matlab.ui.control.Image
        RolledSlot3            matlab.ui.control.Image
        RolledSlot4            matlab.ui.control.Image
        RolledSlot5            matlab.ui.control.Image
        RolledSlot6            matlab.ui.control.Image
        BankScoreButton        matlab.ui.control.Button
        RollDiceButton         matlab.ui.control.Button
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    properties (Access = public)
        DiceArray1 % Description
    end

    properties (Access = private)
        Property % Description
    end

    methods (Access = public)
        
end
    
    methods (Access = private)
        
        function render(app)
            app.RolledSlot(slot).ImageSource = 'dice_' + string(banked) + '.png';      
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DiceArray1 = zeros(1, 6);   % Initializes a 1x6 row array to input dice values into
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            rolledDice = randi(6, 1, 6);
            display(rolledDice);
            % path = 'dice_' + string(rolledDice(1)) + '.png';
            % disp(path);
            app.RolledSlot1.ImageSource = 'dice_' + string(rolledDice(1)) + '.png';
            app.RolledSlot2.ImageSource = 'dice_' + string(rolledDice(2)) + '.png';
            app.RolledSlot3.ImageSource = 'dice_' + string(rolledDice(3)) + '.png';
            app.RolledSlot4.ImageSource = 'dice_' + string(rolledDice(4)) + '.png';
            app.RolledSlot5.ImageSource = 'dice_' + string(rolledDice(5)) + '.png';
            app.RolledSlot6.ImageSource = 'dice_' + string(rolledDice(6)) + '.png';
            [y, Fs] = audioread('roll.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot1
        function RolledSlot1ImageClicked(app, event)
            app.RolledSlot1.Visible = 'off';
            app.BankedSlot1.ImageSource = app.RolledSlot1.ImageSource;
            Die1String = string(app.RolledSlot1.ImageSource);
            Die1ValOnly = erase(Die1String, "dice_"); Die1ValOnly = erase(Die1ValOnly, ".png");
            Die1ValDouble = double(Die1ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die1ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio
        end

        % Image clicked function: RolledSlot2
        function RolledSlot2ImageClicked(app, event)
            app.RolledSlot2.Visible = 'off';
            app.BankedSlot2.ImageSource = app.RolledSlot2.ImageSource;
            Die2String = string(app.RolledSlot2.ImageSource);
            Die2ValOnly = erase(Die2String, "dice_"); Die2ValOnly = erase(Die2ValOnly, ".png");
            Die2ValDouble = double(Die2ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die2ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot3
        function RolledSlot3ImageClicked(app, event)
            app.RolledSlot3.Visible = 'off';
            app.BankedSlot3.ImageSource = app.RolledSlot3.ImageSource;
            Die3String = string(app.RolledSlot3.ImageSource);
            Die3ValOnly = erase(Die3String, "dice_"); Die3ValOnly = erase(Die3ValOnly, ".png");
            Die3ValDouble = double(Die3ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die3ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot4
        function RolledSlot4ImageClicked(app, event)
            app.RolledSlot4.Visible = 'off';
            app.BankedSlot4.ImageSource = app.RolledSlot4.ImageSource;
            Die4String = string(app.RolledSlot4.ImageSource);
            Die4ValOnly = erase(Die4String, "dice_"); Die4ValOnly = erase(Die4ValOnly, ".png");
            Die4ValDouble = double(Die4ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die4ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot5
        function RolledSlot5ImageClicked(app, event)
            app.RolledSlot5.Visible = 'off';
            app.BankedSlot5.ImageSource = app.RolledSlot5.ImageSource;
            Die5String = string(app.RolledSlot5.ImageSource);
            Die5ValOnly = erase(Die5String, "dice_"); Die5ValOnly = erase(Die5ValOnly, ".png");
            Die5ValDouble = double(Die5ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die5ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Image clicked function: RolledSlot6
        function RolledSlot6ImageClicked(app, event)
            app.RolledSlot6.Visible = 'off';
            app.BankedSlot6.ImageSource = app.RolledSlot6.ImageSource;
            Die6String = string(app.RolledSlot6.ImageSource);
            Die6ValOnly = erase(Die6String, "dice_"); Die6ValOnly = erase(Die6ValOnly, ".png");
            Die6ValDouble = double(Die6ValOnly);
            app.DiceArray1 = [app.DiceArray1, Die6ValDouble];
            [y, Fs] = audioread('woosh.mp3'); % Load audio
            sound(y,Fs); % Play the audio

        end

        % Button pushed function: BankScoreButton
        function BankScoreButtonPushed(app, event)
            app.DiceArray1 = nonzeros(app.DiceArray1);  % Removes any empty slots from the array before scoring
            display(app.DiceArray1);

                       uniqueIndex = unique(app.DiceArray1);
if numel(app.DiceArray1) == 6   % 6 dice banked
   % Possibilities: 6 of a kind, 5 + 1, 4 + 2, 3 + 3, 6 different
   if isscalar(uniqueIndex) % six of a kind scenario
       app.Player1Score.Value = app.Player1Score.Value + 3000; % six of any # points
   elseif numel(uniqueIndex) == 2
       % Possibilities: 5 + 1, 4 + 2, 3 + 3
       if sum(uniqueIndex(1)) == 5 || sum(uniqueIndex(2)) == 5
           % 5 + 1 scenario
           if sum(uniqueIndex(1)) == 5 && sum(uniqueIndex(2)) == 1
               if uniqueIndex(2) == 1 || uniqueIndex(2) == 5
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 2000 + 100;   % five of a kind & single 1 points
                   elseif uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 2000 + 50;    % five of a kind & single 5 points
                   end
               elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 2000; % five of a kind points
               end
           elseif sum(uniqueIndex(1)) == 1 && sum(uniqueIndex(2)) == 5
               if uniqueIndex(1) == 1 || uniqueIndex(1) == 5
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 2000 + 100;   % five of a kind & single 1 points
                   elseif uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 2000 + 50;    % five of a kind & single 5 points
                   end
               elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 2000; % five of a kind points
               end
           end
       elseif sum(unique(DiceArray(1))) == 4 || sum(uniqueIndex(2)) == 4
           % 4 + 2 scenario
           if sum(uniqueIndex(1)) == 4 && sum(uniqueIndex(2)) == 2
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 100 + 100; % four of a kind & two 1s points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 50 + 50;   % four of a kind & two 5s points
               elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000; % four of a kind points
               end
           elseif sum(uniqueIndex(1)) == 2 && sum(uniqueIndex(2)) == 4
               if uniqueIndex(1) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 100 + 100; % four of a kind & two 1s points
               elseif uniqueIndex(1) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 50 + 50;   % four of a kind & two 5s points
               elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000; % four of a kind points
               end
           end
       elseif sum(uniqueIndex(1)) == 3 && sum(uniqueIndex(2)) == 3
           % two triplets scenario
           if uniqueIndex(1) == 1
               if uniqueIndex(2) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 200;    % 1s triplet & 2s triplet points
               elseif uniqueIndex(2) == 3
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 300;    % 1s triplet & 3s triplet points
               elseif uniqueIndex(2) == 4
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 400;    % 1s triplet & 4s triplet points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 500;    % 1s triplet & 5s triplet points
               elseif uniqueIndex(2) == 6
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 600;    % 1s triplet & 6s triplet points
               end
           elseif uniqueIndex(1) == 2
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 300;    % 2s triplet & 1s triplet points
               elseif uniqueIndex(2) == 3
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 300;    % 2s triplet & 3s triplet points
               elseif uniqueIndex(2) == 4
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 400;    % 2s triplet & 4s triplet points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 500;    % 2s triplet & 5s triplet points
               elseif uniqueIndex(2) == 6
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 600;    % 2s triplet & 6s triplet points
               end
           elseif uniqueIndex(1) == 3
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 300;    % 3s triplet & 1s triplet points
               elseif uniqueIndex(2) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 200;    % 3s triplet & 2s triplet points
               elseif uniqueIndex(2) == 4
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 400;    % 3s triplet & 4s triplet points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 500;    % 3s triplet & 5s triplet points
               elseif uniqueIndex(2) == 6
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 600;    % 3s triplet & 6s triplet points
               end
           elseif uniqueIndex(1) == 4
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 300;    % 4s triplet & 1s triplet points
               elseif uniqueIndex(2) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 200;    % 4s triplet & 2s triplet points
               elseif uniqueIndex(2) == 3
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 300;    % 4s triplet & 3s triplet points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 500;    % 4s triplet & 5s triplet points
               elseif uniqueIndex(2) == 6
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 600;    % 4s triplet & 6s triplet points
               end
           elseif uniqueIndex(1) == 5
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 300;    % 5s triplet & 1s triplet points
               elseif uniqueIndex(2) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 200;    % 5s triplet & 2s triplet points
               elseif uniqueIndex(2) == 3
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 300;    % 5s triplet & 3s triplet points
               elseif uniqueIndex(2) == 4
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 400;    % 5s triplet & 4s triplet points
               elseif uniqueIndex(2) == 6
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 600;    % 5s triplet & 6s triplet points
               end
           elseif uniqueIndex(1) == 6
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 300;    % 6s triplet & 1s triplet points
               elseif uniqueIndex(2) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 200;    % 6s triplet & 2s triplet points
               elseif uniqueIndex(2) == 3
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 300;    % 6s triplet & 3s triplet points
               elseif uniqueIndex(2) == 4
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 400;    % 6s triplet & 4s triplet points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 500;    % 6s triplet & 5s triplet points
               end
           end
       end
   elseif numel(uniqueIndex) == 3
       % 2, 2, 2 OR 1, 2, 3
       if sum(uniqueIndex(1)) == 2 && sum(uniqueIndex(2)) == 2 ...
               && sum(uniqueIndex(3)) == 2
           % 2, 2, 2 scenario
           if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50 + 50;  % two 1s & two 5s points
           elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
               app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for doubles other than 1 & 5
           end
       elseif sum(uniqueIndex(1)) == 3 || sum(uniqueIndex(2)) == 3 ...
               || sum(uniqueIndex(3)) == 3
           % 1, 2, 3 scenario
           if sum(uniqueIndex(1)) == 3
               if uniqueIndex(1) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;    % 1s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & one 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(1) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100 + 50; % 2s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50 + 50;  % 2s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100;  % 2s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50 + 50;  % 2s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50;    % 2s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(1) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100 + 50; % 3s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50 + 50;  % 3s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100;  % 3s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;  % 3s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50;    % 3s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(1) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100 + 50; % 4s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50 + 50;  % 4s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100;  % 4s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50 + 50;  % 4s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50;    % 4s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(1) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100 + 100;    % 5s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % single 1
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(1) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100 + 50; % 6s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50 + 50;  % 6s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100;  % 6s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50 + 50;  % 6s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50;    % 6s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               end
           elseif sum(uniqueIndex(2)) == 3
               if uniqueIndex(2) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;    % 1s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & one 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(2) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100 + 50; % 2s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50 + 50;  % 2s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100;  % 2s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50 + 50;  % 2s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50;    % 2s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(2) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100 + 50; % 3s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50 + 50;  % 3s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100;  % 3s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;  % 3s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50;    % 3s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(2) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100 + 50; % 4s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50 + 50;  % 4s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100;  % 4s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50 + 50;  % 4s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50;    % 4s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(2) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100 + 100;    % 5s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % single 1
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(2) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100 + 50; % 6s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50 + 50;  % 6s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100;  % 6s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50 + 50;  % 6s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50;    % 6s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               end
           elseif sum(uniqueIndex(3)) == 3
               if uniqueIndex(3) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;    % 1s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & one 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(3) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100 + 50; % 2s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50 + 50;  % 2s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100;  % 2s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50 + 50;  % 2s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 200 + 50;    % 2s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(3) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100 + 50; % 3s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50 + 50;  % 3s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100;  % 3s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;  % 3s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 300 + 50;    % 3s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(3) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100 + 50; % 4s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50 + 50;  % 4s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100;  % 4s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50 + 50;  % 4s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 400 + 50;    % 4s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               elseif uniqueIndex(3) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100 + 100;    % 5s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % single 1
                           app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(3) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1   % two 1s & one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100 + 50; % 6s triplet, two 1s, & single 5 points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2   % one 1 & two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50 + 50;  % 6s triplet, single 1, & two 5s points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       if sum(ismember(app.DiceArray1, 1)) == 2    % two 1s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100;  % 6s triplet & two 1s points
                       elseif sum(ismember(app.DiceArray1, 1)) == 1    % one 1
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       if sum(ismember(app.DiceArray1, 5)) == 2    % two 5s
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50 + 50;  % 6s triplet & two 5s points
                       elseif sum(ismember(app.DiceArray1, 5)) == 1    % one 5
                           app.Player1Score.Value = app.Player1Score.Value + 600 + 50;    % 6s triplet & single 5 points
                       end
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points (no points for
                   end                                     % singles/doubles other than 1 & 5)
               end
           end
       end
   elseif numel(uniqueIndex) == 4
       % Possibility of triplets...
       if sum(app.DiceArray1 == uniqueIndex(1)) == 3 || sum(app.DiceArray1 == uniqueIndex(2)) == 3 ...
               || sum(app.DiceArray1 == uniqueIndex(3)) == 3 || sum(app.DiceArray1 == uniqueIndex(4)) == 3
           % Triplet + 1 + 1 + 1 scenario
           if sum(app.DiceArray1 == uniqueIndex(1)) == 3
               if uniqueIndex(1) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(1) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(1) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(1) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(1) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(1) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           elseif sum(app.DiceArray1 == uniqueIndex(2)) == 3
               if uniqueIndex(2) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(2) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(2) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(2) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(2) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(2) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           elseif sum(app.DiceArray1 == uniqueIndex(3)) == 3
               if uniqueIndex(3) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(3) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(3) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(3) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(3) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(3) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           elseif sum(app.DiceArray1 == uniqueIndex(4)) == 3
               if uniqueIndex(4) == 1   % 1s triplet
                   if sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(4) == 2   % 2s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(4) == 3   % 3s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(4) == 4   % 4s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(4) == 5   % 5s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100; % 5s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(4) == 6   % 6s triplet
                   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           end
       elseif sum(uniqueIndex(1)) == 2 || sum(uniqueIndex(2)) == 2 ...
               || sum(uniqueIndex(3)) == 2 || sum(uniqueIndex(4)) == 2
           % Pair + pair + 1 + 1 scenario
           if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
               if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50 + 50;  % two 1s & two 5s points
               elseif sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50;   % two 1s & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 100 + 50 + 50;    % single 1 & two 5s points
               elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
               end
           elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
               if sum(ismember(app.DiceArray1, 1)) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
               elseif sum(ismember(app.DiceArray1, 1)) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
               end
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
               if sum(ismember(app.DiceArray1, 5)) == 2
                   app.Player1Score.Value = app.Player1Score.Value + 50 + 50;    % two 5s points
               elseif sum(ismember(app.DiceArray1, 5)) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 50;  % single 5 points
               end
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
               app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles/pairs other than 1 & 5
           end
       end
   elseif numel(uniqueIndex) == 5
       % Single 1 & single 5 get points, as well as two 1s or two 5s
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1 == 1) == 2 && sum(app.DiceArray1 == 5) == 1   % two 1s & one 5
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50;   % two 1s & single 5 points
           elseif sum(DiceAray1 == 1) == 1 && sum(app.DiceArray1 == 5) == 2    % two 5s & one 1
               app.Player1Score.Value = app.Player1Score.Value + 100 + 50 + 50;    % single 1 & two 5s points
           elseif sum(app.DiceArray1 == 1) == 1 && sum(app.DiceArray1 == 5) == 1   % one 1 & one 5
               app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
           if sum(app.DiceArray1 == 1) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
           elseif sum(app.DiceArray1 == 1) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1 == 5) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(app.DiceArray1 == 5) == 1
               app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles/pairs other than 1 & 5
       end
   elseif numel(uniqueIndex) == 6 % 1--6 run scenario
       app.Player1Score.Value = app.Player1Score.Value + 1500; % one of each # points
   end
elseif numel(app.DiceArray1) == 5   % 5 dice banked
   % Possibilities: 5 of a kind, 4 + 1, 3 + 2, 5 different
   if isscalar(uniqueIndex) % five of a kind scenario
       app.Player1Score.Value = app.Player1Score.Value + 2000; % five of any # points
   elseif numel(uniqueIndex) == 2 % 4 + 1 & 3 + 2 scenarios
       if sum(uniqueIndex(1)) == 4 || sum(uniqueIndex(2)) == 4   % 4 + 1 scenario
           if sum(unique(DiceArray(1))) == 4 || sum(unique(DiceArray(2))) == 1
               if uniqueIndex(2) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 100;   % four of any # & single 1 points
               elseif uniqueIndex(2) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 50;    % four of any # & single 5 points
               elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000; % four of any # points
               end
           elseif sum(unique(DiceArray(1))) == 1 || sum(unique(DiceArray(2))) == 4
               if uniqueIndex(1) == 1
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 100;   % four of any # & single 1 points
               elseif uniqueIndex(1) == 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000 + 50;    % four of any # & single 5 points
               elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                   app.Player1Score.Value = app.Player1Score.Value + 1000; % four of any # points
               end
           end
       elseif sum(uniqueIndex(1)) == 3 || sum(uniqueIndex(2)) == 3   % 3 + 2 scenario
           if sum(uniqueIndex(1)) == 3 && sum(uniqueIndex(2)) == 2
               if uniqueIndex(1) == 1
                   if uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50; % 1s triplet & two 5s points
                   elseif uniqueIndex(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(1) == 2
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100;  % 2s triplet & two 1s points
                   elseif uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50 + 50;    % 2s triplet & two 5s points
                   elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(1) == 3
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100;  % 3s triplet & two 1s points
                   elseif uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;    % 3s triplet & two 5s points
                   elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(1) == 4
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100;  % 4s triplet & two 1s points
                   elseif uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50 + 50;    % 4s triplet & two 5s points
                   elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(1) == 5
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100 + 100;  % 5s triplet & two 1s points
                   elseif uniqueIndex(2) ~= 1
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(1) == 6
                   if uniqueIndex(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100;  % 6s triplet & two 1s points
                   elseif uniqueIndex(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50 + 50;    % 6s triplet & two 5s points
                   elseif uniqueIndex(2) ~= 1 && uniqueIndex(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           elseif sum(uniqueIndex(1)) == 2 && sum(uniqueIndex(2)) == 3
               if uniqueIndex(2) == 1
                   if uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50; % 1s triplet & two 5s points
                   elseif uniqueIndex(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif uniqueIndex(2) == 2
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 100;  % 2s triplet & two 1s points
                   elseif uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50 + 50;    % 2s triplet & two 5s points
                   elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif uniqueIndex(1) == 3
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 100;  % 3s triplet & two 1s points
                   elseif uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50 + 50;    % 3s triplet & two 5s points
                   elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif uniqueIndex(2) == 4
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 100;  % 4s triplet & two 1s points
                   elseif uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50 + 50;    % 4s triplet & two 5s points
                   elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif uniqueIndex(2) == 5
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100 + 100;  % 5s triplet & two 1s points
                   elseif uniqueIndex(1) ~= 1
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif uniqueIndex(2) == 6
                   if uniqueIndex(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 100;  % 6s triplet & two 1s points
                   elseif uniqueIndex(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50 + 50;    % 6s triplet & two 5s points
                   elseif uniqueIndex(1) ~= 1 && uniqueIndex(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           end
       end
   elseif numel(uniqueIndex) == 3
       % Maximum amount of any number is 3 --> triplets for any # gets
       % points, otherwise only 1s & 5s get points for singles
       if sum(uniqueIndex(1)) == 3
           if uniqueIndex(1) == 1
               if sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
               end
           elseif uniqueIndex(1) == 2
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
               end
           elseif uniqueIndex(1) == 3
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
               end
           elseif uniqueIndex(1) == 4
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
               end
           elseif uniqueIndex(1) == 5
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 100;    % 5s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
               end
           elseif uniqueIndex(1) == 6
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
               end
           end
       elseif sum(uniqueIndex(2)) == 3
           if uniqueIndex(2) == 1
               if sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
               end
           elseif uniqueIndex(2) == 2
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
               end
           elseif uniqueIndex(2) == 3
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
               end
           elseif uniqueIndex(2) == 4
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
               end
           elseif uniqueIndex(2) == 5
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 100;    % 5s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
               end
           elseif uniqueIndex(2) == 6
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
               end
           end
       elseif sum(uniqueIndex(3)) == 3
           if uniqueIndex(3) == 1
               if sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
               end
           elseif uniqueIndex(3) == 2
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100 + 50;   % 2s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
               end
           elseif uniqueIndex(3) == 3
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100 + 50;   % 3s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
               end
           elseif uniqueIndex(3) == 4
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100 + 50;   % 4s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
               end
           elseif uniqueIndex(3) == 5
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500 + 100;    % 5s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
               end
           elseif uniqueIndex(3) == 6
               if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100 + 50;   % 6s triplet, single 1, & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
                   app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
               elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
                   app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
               end
           end
       end
   elseif numel(uniqueIndex) == 4
       % Maximum amount of any number is 2 --> only 1s and 5s wil get any points
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           if sum(ismember(app.DiceArray1, 1)) == 2 && sum(ismember(app.DiceArray1, 5)) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50;   % two 1s & single 5 points
           elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 50 + 50;    % single 1 & two 5s points
           elseif sum(ismember(app.DiceArray1, 1)) == 1 && sum(ismember(app.DiceArray1, 5)) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
           if sum(ismember(app.DiceArray1, 1)) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
           elseif sum(ismember(app.DiceArray1, 1)) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           if sum(ismember(app.DiceArray1, 5)) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(ismember(app.DiceArray1, 5)) == 1
               app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles/pairs other than 1 & 5
       end
   elseif numel(uniqueIndex) == 5 % five different #s scenario
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
       elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than 1 & 5
       end
   end
elseif numel(app.DiceArray1) == 4   % 4 dice banked
   % Possibilities: 4 of a kind, 3 + single, 2 + 2, 4 different
   if isscalar(uniqueIndex) % four of a kind scenario
       app.Player1Score.Value = app.Player1Score.Value + 1000; % four of any # points
   elseif numel(uniqueIndex) == 2 % two pairs scenario AND triplet + single scenario
       if sum(uniqueIndex(1)) == 3 || sum(uniqueIndex(2)) == 3   % triplet & single scenario
           if sum(uniqueIndex(1)) == 3
               if app.DiceArray1(1) == 1   % 1s triplet & one other # scenario
                   if app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif app.DiceArray1(1) == 2   % 2s triplet & one other # scenario
                   if app.DiceArray1(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif app.DiceArray1(1) == 3   % 3s triplet & one other # scenario
                   if app.DiceArray1(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif app.DiceArray1(1) == 4   % 4s triplet & one other # scenario
                   if app.DiceArray1(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif app.DiceArray1(1) == 5   % 5s triplet & one other # scenario
                   if app.DiceArray1(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100;    % 5s triplet & single 1 points
                   elseif app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 50; % 5s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif app.DiceArray1(1) == 6   % 6s triplet & one other # scenario
                   if app.DiceArray1(2) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif app.DiceArray1(2) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif app.DiceArray1(2) ~= 1 && app.DiceArray1(2) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           elseif sum(uniqueIndex(2)) == 3
               if app.DiceArray1(2) == 1   % 1s triplet & one other # scenario
                   if app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 1s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5

                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
                   end
               elseif app.DiceArray1(2) == 2   % 2s triplet & one other # scenario
                   if app.DiceArray1(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 100;    % 2s triplet & single 1 points
                   elseif app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 200 + 50; % 2s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
                   end
               elseif app.DiceArray1(2) == 3   % 3s triplet & one other # scenario
                   if app.DiceArray1(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 100;    % 3s triplet & single 1 points
                   elseif app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 300 + 50; % 3s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
                   end
               elseif app.DiceArray1(2) == 4   % 4s triplet & one other # scenario
                   if app.DiceArray1(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 100;    % 4s triplet & single 1 points
                   elseif app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 400 + 50; % 4s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
                   end
               elseif app.DiceArray1(2) == 5   % 5s triplet & one other # scenario
                   if app.DiceArray1(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 100;    % 5s triplet & single 1 points
                   elseif app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 500 + 50; % 5s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
                   end
               elseif app.DiceArray1(2) == 6   % 6s triplet & one other # scenario
                   if app.DiceArray1(1) == 1
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 100;    % 6s triplet & single 1 points
                   elseif app.DiceArray1(1) == 5
                       app.Player1Score.Value = app.Player1Score.Value + 600 + 50; % 6s triplet & single 5 points
                   elseif app.DiceArray1(1) ~= 1 && app.DiceArray1(1) ~= 5
                       app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
                   end
               end
           end
       elseif sum(uniqueIndex(1)) == 2 && sum(uniqueIndex(2)) == 2   % two pairs scenario
           if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50 + 50;  % two 1s & two 5s points
           elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
               app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than 1 & 5
           end
       end
   elseif numel(uniqueIndex) == 3
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1, 1) == 1 && sum(app.DiceArray1, 5) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
           elseif sum(app.DiceArray1, 1) == 2 && sum(app.DiceArray1, 5) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50;   % two 1s & single 5 points
           elseif sum(app.DiceArray1, 1) == 1 && sum(app.DiceArray1, 5) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50 + 100;    % two 5s & single 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == true && ismember(Dice, 5) == false
           if sum(app.DiceArray1 == 1) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;   % two 1s points
           elseif sum(app.DiceArray1 == 1) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1, 5) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(app.DiceArray1, 5) == 1
               app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than
       end                                     % 1 & 5, or for doubles
   elseif numel(uniqueIndex) == 4
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
       elseif sum(ismember(app.DiceArray1, 1)) == true && ismember(Dice, 5) == false
           app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than 1 & 5
       end
   end
elseif numel(app.DiceArray1) == 3   % 3 dice banked
   % Possibilities: All triplets, pair + 1, 1 of each of 3 different #s
   if isscalar(uniqueIndex)  % Triplets scenarios
       if unique(app.DiceArray1) == 1
           app.Player1Score.Value = app.Player1Score.Value + 300;  % 1s triplet points
       elseif unique(app.DiceArray1) == 2
           app.Player1Score.Value = app.Player1Score.Value + 200;  % 2s triplet points
       elseif unique(app.DiceArray1) == 3
           app.Player1Score.Value = app.Player1Score.Value + 300;  % 3s triplet points
       elseif unique(app.DiceArray1) == 4
           app.Player1Score.Value = app.Player1Score.Value + 400;  % 4s triplet points
       elseif unique(app.DiceArray1) == 5
           app.Player1Score.Value = app.Player1Score.Value + 500;  % 5s triplet points
       elseif unique(app.DiceArray1) == 6
           app.Player1Score.Value = app.Player1Score.Value + 600;  % 6s triplet points
       end
   elseif numel(uniqueIndex) == 2 % Pair + single scenarios
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1 == 1) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100 + 50;   % two 1s & one 5 points
           elseif sum(app.DiceArray1 == 5) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50 + 100;    % two 5s & one 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
           if sum(app.DiceArray1, 1) == 2
               app.Player1Score.Value = app.Player1Score.Value + 100 + 100;    % two 1s points
           elseif sum(app.DiceArray1, 1) == 1
               app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           if sum(app.DiceArray1, 5) == 2
               app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
           elseif sum(app.DiceArray1, 5) == 1
               app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
           end
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than
       end                                     % 1 & 5, or for doubles
   elseif numel(uniqueIndex) == 3 % three singles scenarios
       if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1, single 5, & one other single points
       elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 & two other singles points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
           app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 & two other singles points
       elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
           app.Player1Score.Value = app.Player1Score.Value + 0;    % No points for singles other than 1 & 5
       end
   end
elseif numel(app.DiceArray1) == 2   % 2 dice banked
   % Possibilities: All pairs, 1 of each of 2 different #s
   if sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == true
       app.Player1Score.Value = app.Player1Score.Value + 100 + 50; % single 1 & single 5 points
   elseif sum(ismember(app.DiceArray1, 1)) == true && sum(ismember(app.DiceArray1, 5)) == false
       if sum(ismember(app.DiceArray1, 1)) == 1
           app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
       elseif sum(ismember(app.DiceArray1, 1)) == 2
           app.Player1Score.Value = app.Player1Score.Value + 100 + 100;  % two 1s points
       end
   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == true
       if sum(ismember(app.DiceArray1, 5)) == 1
           app.Player1Score.Value = app.Player1Score.Value + 50;  % single 5 points
       elseif sum(ismember(app.DiceArray1, 5)) == 2
           app.Player1Score.Value = app.Player1Score.Value + 50 + 50;  % two 5s points
       end
   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
       app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for pairs
   end
elseif isscalar(app.DiceArray1) == true     % 1 die banked
   if sum(ismember(app.DiceArray1, 1)) == true
       app.Player1Score.Value = app.Player1Score.Value + 100;  % single 1 points
   elseif sum(ismember(app.DiceArray1, 5)) == true
       app.Player1Score.Value = app.Player1Score.Value + 50;   % single 5 points
   elseif sum(ismember(app.DiceArray1, 1)) == false && sum(ismember(app.DiceArray1, 5)) == false
       app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for singles other than 1 & 5
   end
elseif numel(app.DiceArray1) == 0   % no dice banked
   app.Player1Score.Value = app.Player1Score.Value + 0;    % no points for no dice banked
end
%{ Maybe save points from each roll to a temporary array, which is cleared
%  if a farkle is rolled ... and each time "roll dice" is clicked, the score
%  is added to the temp array }%

        end

        % Value changed function: Player1Score
        function Player1ScoreValueChanged(app, event)
            value = app.Player1Score.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.BankScoreButton = uibutton(app.UIFigure, 'push');
            app.BankScoreButton.ButtonPushedFcn = createCallbackFcn(app, @BankScoreButtonPushed, true);
            app.BankScoreButton.Text = 'Bank Score';
            app.Rolled = uipanel(app.UIFigure);
            app.RolledSlot6 = uiimage(app.Rolled);
            app.RolledSlot6.ImageClickedFcn = createCallbackFcn(app, @RolledSlot6ImageClicked, true);
            app.RolledSlot5 = uiimage(app.Rolled);
            app.RolledSlot5.ImageClickedFcn = createCallbackFcn(app, @RolledSlot5ImageClicked, true);
            app.RolledSlot4 = uiimage(app.Rolled);
            app.RolledSlot4.ImageClickedFcn = createCallbackFcn(app, @RolledSlot4ImageClicked, true);
            app.RolledSlot3 = uiimage(app.Rolled);
            app.RolledSlot3.ImageClickedFcn = createCallbackFcn(app, @RolledSlot3ImageClicked, true);
            app.RolledSlot2 = uiimage(app.Rolled);
            app.RolledSlot2.ImageClickedFcn = createCallbackFcn(app, @RolledSlot2ImageClicked, true);
            app.RolledSlot1 = uiimage(app.Rolled);
            app.RolledSlot1.ImageClickedFcn = createCallbackFcn(app, @RolledSlot1ImageClicked, true);
            app.Banked1 = uipanel(app.UIFigure);
            app.BankedSlot6 = uiimage(app.Banked1);
            app.BankedSlot5 = uiimage(app.Banked1);
            app.BankedSlot4 = uiimage(app.Banked1);
            app.BankedSlot3 = uiimage(app.Banked1);
            app.BankedSlot2 = uiimage(app.Banked1);
            app.BankedSlot1 = uiimage(app.Banked1);
            app.Scores = uipanel(app.UIFigure);
            app.Scores.Title = 'Scores';
            app.Player1EditFieldLabel = uilabel(app.Scores);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1Score = uieditfield(app.Scores, 'numeric');
            app.Player1Score.ValueChangedFcn = createCallbackFcn(app, @Player1ScoreValueChanged, true);
            app.Player1Score.Editable = 'off';
            app.Player2EditFieldLabel = uilabel(app.Scores);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2Score = uieditfield(app.Scores, 'numeric');
            app.Player2Score.Editable = 'off';
            app.ScoringGuide = uipanel(app.UIFigure);
            app.ScoringGuide.Title = 'Scoring Guide';
            app.TextAreaLabel = uilabel(app.ScoringGuide);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.ScoringGuide);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Single 1 = 100 pts.'; 'Single 5 = 50 pts.'; 'Three 1s = 300 pts.'; 'Three 2s = 200 pts.'; 'Three 3s = 300 pts.'; 'Three 4s = 400 pts.'; 'Three 5s = 500 pts.'; 'Three 6s = 600 pts.'; 'Four of a Kind = 1,000 pts.'; 'Five of a Kind = 2,000 pts.'; 'Six of a Kind = 3,000 pts.'; '1, 2, 3, 4, 5, 6 = 1,500 pts.'; 'Three Pairs = 1,500 pts.'; 'Four of a Kind w/ a Pair = 1,500 pts.'; 'Two Triplets = 2,500 pts.'};
            app.Banked2 = uipanel(app.UIFigure);
            app.BankedSlot6_2 = uiimage(app.Banked2);
            app.BankedSlot5_2 = uiimage(app.Banked2);
            app.BankedSlot4_2 = uiimage(app.Banked2);
            app.BankedSlot3_2 = uiimage(app.Banked2);
            app.BankedSlot2_2 = uiimage(app.Banked2);
            app.BankedSlot1_2 = uiimage(app.Banked2);
            app.Player2Pic = uiimage(app.UIFigure);
            app.Player2Pic.ImageSource = fullfile(pathToMLAPP, 'Player2.png');
            app.Player1Pic = uiimage(app.UIFigure);
            app.Player1Pic.ImageSource = fullfile(pathToMLAPP, 'Player1.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end